classdef giaodien < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        TayphiLabel    matlab.ui.control.Label
        TaytriLabel    matlab.ui.control.Label
        R_IKButton     matlab.ui.control.Button
        L_IKButton     matlab.ui.control.Button
        R7Slider       matlab.ui.control.Slider
        R7SliderLabel  matlab.ui.control.Label
        R6Slider       matlab.ui.control.Slider
        R6SliderLabel  matlab.ui.control.Label
        R5Slider       matlab.ui.control.Slider
        R5SliderLabel  matlab.ui.control.Label
        R4Slider       matlab.ui.control.Slider
        R4SliderLabel  matlab.ui.control.Label
        R3Slider       matlab.ui.control.Slider
        R3SliderLabel  matlab.ui.control.Label
        R2Slider       matlab.ui.control.Slider
        R2SliderLabel  matlab.ui.control.Label
        R1Slider       matlab.ui.control.Slider
        R1SliderLabel  matlab.ui.control.Label
        L7Slider       matlab.ui.control.Slider
        L7SliderLabel  matlab.ui.control.Label
        L6Slider       matlab.ui.control.Slider
        L6SliderLabel  matlab.ui.control.Label
        L5Slider       matlab.ui.control.Slider
        L5SliderLabel  matlab.ui.control.Label
        L4Slider       matlab.ui.control.Slider
        L4SliderLabel  matlab.ui.control.Label
        L3Slider       matlab.ui.control.Slider
        L3SliderLabel  matlab.ui.control.Label
        L2Slider       matlab.ui.control.Slider
        L2SliderLabel  matlab.ui.control.Label
        L1Slider       matlab.ui.control.Slider
        L1SliderLabel  matlab.ui.control.Label
        RzLabel        matlab.ui.control.Label
        RyLabel        matlab.ui.control.Label
        RxLabel        matlab.ui.control.Label
        EditField_20   matlab.ui.control.NumericEditField
        EditField_19   matlab.ui.control.NumericEditField
        EditField_18   matlab.ui.control.NumericEditField
        CLOSEButton    matlab.ui.control.Button
        DEFAULTButton  matlab.ui.control.Button
        RUNButton      matlab.ui.control.Button
        LzLabel        matlab.ui.control.Label
        LyLabel        matlab.ui.control.Label
        LxLabel        matlab.ui.control.Label
        EditField_17   matlab.ui.control.NumericEditField
        EditField_16   matlab.ui.control.NumericEditField
        EditField_15   matlab.ui.control.NumericEditField
        EditField_14   matlab.ui.control.NumericEditField
        EditField_13   matlab.ui.control.NumericEditField
        EditField_12   matlab.ui.control.NumericEditField
        EditField_11   matlab.ui.control.NumericEditField
        EditField_10   matlab.ui.control.NumericEditField
        EditField_9    matlab.ui.control.NumericEditField
        EditField_8    matlab.ui.control.NumericEditField
        EditField_7    matlab.ui.control.NumericEditField
        EditField_6    matlab.ui.control.NumericEditField
        EditField_5    matlab.ui.control.NumericEditField
        EditField_4    matlab.ui.control.NumericEditField
        EditField_3    matlab.ui.control.NumericEditField
        EditField_2    matlab.ui.control.NumericEditField
        EditField      matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)
        
        
       % ==========================================
        % 1. HÀM CẬP NHẬT CHUNG (Đẩy Workspace & Tính FK cả 2 tay)
        % ==========================================
        function UpdateRobot(app)
            q_left = [
                app.EditField.Value, app.EditField_2.Value, app.EditField_3.Value, ...
                app.EditField_4.Value, app.EditField_5.Value, app.EditField_6.Value, app.EditField_7.Value
            ];
            q_right = [
                app.EditField_8.Value, app.EditField_9.Value, app.EditField_10.Value, ...
                app.EditField_11.Value, app.EditField_12.Value, app.EditField_13.Value, app.EditField_14.Value
            ];
         
            modelName = 'YuMi_Simscape1';
         
            % ---- Tên khối Constant ứng với q_left(1..7) / q_right(1..7) trong Simulink ----
            leftBlocks  = {'Constant','Constant3','Constant5','Constant7','Constant9','Constant11','Constant12'};
            rightBlocks = {'Constant1','Constant2','Constant4','Constant6','Constant8','Constant10','Constant13'};
         
            try
                simStatus = get_param(modelName, 'SimulationStatus');
            catch
                simStatus = 'stopped';
            end
         
            if strcmp(simStatus, 'running') || strcmp(simStatus, 'paused')
                % ---- SIM ĐANG CHẠY: tune giá trị trực tiếp, không biên dịch lại ----
                for i = 1:7
                    set_param([modelName '/' leftBlocks{i}],  'Value', num2str(q_left(i)));
                    set_param([modelName '/' rightBlocks{i}], 'Value', num2str(q_right(i)));
                end
            else
                % Sim chưa chạy -> cập nhật biến workspace
                assignin('base', 'q_left', q_left);
                assignin('base', 'q_right', q_right);
            end
         
            % --- TÍNH FK TAY TRÁI (Theo đúng chuỗi URDF: 1 -> 2 -> 7 -> 3 -> 4 -> 5 -> 6) ---
            pos_l = app.forwardKinematicsLeft(q_left);
            app.EditField_15.Value = pos_l(1);
            app.EditField_16.Value = pos_l(2);
            app.EditField_17.Value = pos_l(3);
         
            % --- TÍNH FK TAY PHẢI ---
            pos_r = app.forwardKinematicsRight(q_right);
            app.EditField_18.Value = pos_r(1);
            app.EditField_19.Value = pos_r(2);
            app.EditField_20.Value = pos_r(3);
        end
        
        function pos = forwardKinematicsLeft(app, q)
            pos = app.forwardKinematicsLeft_rad(deg2rad(q));
        end
 
        function q_opt = inverseKinematicsLeft(app, target_pos)
            q_current = [
                app.EditField.Value, app.EditField_2.Value, app.EditField_3.Value, ...
                app.EditField_4.Value, app.EditField_5.Value, app.EditField_6.Value, app.EditField_7.Value
            ];
            theta_init = deg2rad(q_current);
            costFunction = @(theta) norm(app.forwardKinematicsLeft_rad(theta) - target_pos);
            lb = deg2rad([-168, -143, -168, -123, -290, -88, -229]);
            ub = deg2rad([ 168,   43,  168,   80,  290, 138,  229]);
            options = optimoptions('fmincon', 'Algorithm', 'sqp', 'Display', 'off');
            [theta_opt, ~] = fmincon(costFunction, theta_init, [], [], [], [], lb, ub, [], options);
            q_opt = rad2deg(theta_opt);
        end
 
        function pos = forwardKinematicsLeft_rad(app, theta)
            % Thứ tự khớp VẬT LÝ [1,2,7,3,4,5,6] - theta truyền vào phải
            % theo đúng thứ tự này (giống EditField..EditField_7 hiện tại:
            % L1,L2,L3,L4,L5,L6,L7 -> chính là vị trí vật lý 1,2,7,3,4,5,6)
            joints = app.yumiParamsLeft();
            theta = theta(:);
            T = eye(4);
            for i = 1:7
                T = T * app.originTransform(joints(i).rpy, joints(i).xyz) * app.jointRotZ(theta(i));
            end
            pos = T(1:3,4)'; % vị trí so với gốc yumi_base_link (tâm đáy thân robot)
        end
 
 
        % ==========================================
        % 5. CÁC HÀM TÍNH TOÁN HÌNH HỌC (FK & IK TAY PHẢI)
        % ĐÃ SỬA: cùng cấu trúc, chỉ khác origin khớp 1 (đúng URDF)
        % ==========================================
        function pos = forwardKinematicsRight(app, q)
            pos = app.forwardKinematicsRight_rad(deg2rad(q));
        end
 
        function q_opt = inverseKinematicsRight(app, target_pos)
            q_current = [
                app.EditField_8.Value, app.EditField_9.Value, app.EditField_10.Value, ...
                app.EditField_11.Value, app.EditField_12.Value, app.EditField_13.Value, app.EditField_14.Value
            ];
            theta_init = deg2rad(q_current);
            costFunction = @(theta) norm(app.forwardKinematicsRight_rad(theta) - target_pos);
            lb = deg2rad([-168, -143, -168, -123, -290, -88, -229]);
            ub = deg2rad([ 168,   43,  168,   80,  290, 138,  229]);
            options = optimoptions('fmincon', 'Algorithm', 'sqp', 'Display', 'off');
            [theta_opt, ~] = fmincon(costFunction, theta_init, [], [], [], [], lb, ub, [], options);
            q_opt = rad2deg(theta_opt);
        end
 
        function pos = forwardKinematicsRight_rad(app, theta)
            joints = app.yumiParamsRight();
            theta = theta(:);
            T = eye(4);
            for i = 1:7
                T = T * app.originTransform(joints(i).rpy, joints(i).xyz) * app.jointRotZ(theta(i));
            end
            pos = T(1:3,4)';
        end
 
 
        % ==========================================
        % 6. HÀM PHỤ TRỢ MA TRẬN BIẾN ĐỔI (THAY CHO trotz/trotz_rad CŨ)
        % Tự viết toàn bộ, không dùng eul2rotm/robotics toolbox nào.
        % ==========================================
        function R = rpy2rotManual(~, roll, pitch, yaw)
            % Quy ước URDF: R = Rz(yaw) * Ry(pitch) * Rx(roll)
            Rx = [1 0 0; 0 cos(roll) -sin(roll); 0 sin(roll) cos(roll)];
            Ry = [cos(pitch) 0 sin(pitch); 0 1 0; -sin(pitch) 0 cos(pitch)];
            Rz = [cos(yaw) -sin(yaw) 0; sin(yaw) cos(yaw) 0; 0 0 1];
            R = Rz * Ry * Rx;
        end
 
        function T = originTransform(app, rpy, xyz)
            R = app.rpy2rotManual(rpy(1), rpy(2), rpy(3));
            T = [R, xyz(:); 0 0 0 1];
        end
 
        function T = jointRotZ(~, q)
            c = cos(q); s = sin(q);
            T = [c -s 0 0; s c 0 0; 0 0 1 0; 0 0 0 1];
        end
 
        function joints = yumiParamsLeft(~)
            % Copy nguyên văn từ abbYuMi_mesh1.urdf, đúng thứ tự vật lý 1,2,7,3,4,5,6
            rpy_list = { [0.97810000 -0.57160000  2.31800000], ...
                         [1.57079633  0.00000000  0.00000000], ...
                         [-1.57079633 0.00000000  0.00000000], ...
                         [1.57079633 -1.57079633  0.00000000], ...
                         [-1.57079633 0.00000000  0.00000000], ...
                         [1.57079633  0.00000000  0.00000000], ...
                         [-1.57079633 0.00000000  0.00000000] };
            xyz_list = { [0.05355000  0.07250000  0.41492000], ...
                         [0.03000000  0.00000000  0.10000000], ...
                         [-0.03000000 0.17283000  0.00000000], ...
                         [-0.04188000 0.00000000  0.07873000], ...
                         [0.04050000  0.16461000  0.00000000], ...
                         [-0.02700000 0.00000000  0.10039000], ...
                         [0.02700000  0.02900000  0.00000000] };
            joints = struct('rpy', rpy_list, 'xyz', xyz_list);
        end
 
        function joints = yumiParamsRight(~)
            % Giống hệt tay trái, CHỈ khác khớp 1 (đúng URDF)
            rpy_list = { [-0.97950000 -0.56820000 -2.31550000], ...
                         [1.57079633  0.00000000  0.00000000], ...
                         [-1.57079633 0.00000000  0.00000000], ...
                         [1.57079633 -1.57079633  0.00000000], ...
                         [-1.57079633 0.00000000  0.00000000], ...
                         [1.57079633  0.00000000  0.00000000], ...
                         [-1.57079633 0.00000000  0.00000000] };
            xyz_list = { [0.05355000 -0.07250000  0.41492000], ...
                         [0.03000000  0.00000000  0.10000000], ...
                         [-0.03000000 0.17283000  0.00000000], ...
                         [-0.04188000 0.00000000  0.07873000], ...
                         [0.04050000  0.16461000  0.00000000], ...
                         [-0.02700000 0.00000000  0.10039000], ...
                         [0.02700000  0.02900000  0.00000000] };
            joints = struct('rpy', rpy_list, 'xyz', xyz_list);
        end
        
        
    end 


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EditField, L1Slider
        function L1SliderValueChanged(app, event)
            app.EditField.Value = app.L1Slider.Value;
            app.UpdateRobot();
            
        end

        % Value changed function: EditField_2, L2Slider
        function L2SliderValueChanged(app, event)
            app.EditField_2.Value = app.L2Slider.Value;
            app.UpdateRobot();
        end

        % Value changed function: EditField_3, L3Slider
        function L3SliderValueChanged(app, event)
            app.EditField_3.Value =app.L3Slider.Value;
            app.UpdateRobot();
            
        end

        % Value changed function: EditField_4, L4Slider
        function L4SliderValueChanged(app, event)
             app.EditField_4.Value =app.L4Slider.Value;
             app.UpdateRobot();
            
        end

        % Value changed function: EditField_5, L5Slider
        function L5SliderValueChanged(app, event)
            app.EditField_5.Value = app.L5Slider.Value;
            app.UpdateRobot();
            
        end

        % Value changed function: EditField_6, L6Slider
        function L6SliderValueChanged(app, event)
            app.EditField_6.Value = app.L6Slider.Value;
            app.UpdateRobot();
            
        end

        % Value changed function: EditField_7, L7Slider
        function L7SliderValueChanged(app, event)
            app.EditField_7.Value = app.L7Slider.Value;
            app.UpdateRobot();
            
        end

        % Value changed function: EditField_8, R1Slider
        function R1SliderValueChanged(app, event)
            app.EditField_8.Value = app.R1Slider.Value;
            app.UpdateRobot();
            
        end

        % Value changed function: EditField_9, R2Slider
        function R2SliderValueChanged(app, event)
            app.EditField_9.Value = app.R2Slider.Value;
            app.UpdateRobot();
            
        end

        % Value changed function: EditField_10, R3Slider
        function R3SliderValueChanged(app, event)
            app.EditField_10.Value = app.R3Slider.Value;
            app.UpdateRobot();
            
        end

        % Value changed function: EditField_11, R4Slider
        function EditField_11ValueChanged(app, event)
            app.EditField_11.Value = app.R4Slider.Value;
            app.UpdateRobot();
            
        end

        % Value changed function: EditField_12, R5Slider
        function R5SliderValueChanged(app, event)
            app.EditField_12.Value = app.R5Slider.Value;
            app.UpdateRobot();
            
        end

        % Value changed function: EditField_13, R6Slider
        function R6SliderValueChanged(app, event)
            app.EditField_13.Value = app.R6Slider.Value;
            app.UpdateRobot();
            
        end

        % Value changed function: EditField_14, R7Slider
        function R7SliderValueChanged(app, event)
            app.EditField_14.Value = app.R7Slider.Value;
            app.UpdateRobot();
            
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
              modelName = 'YuMi_Simscape1';
            
            % 1. Đảm bảo mô hình đã được load vào bộ nhớ
            if ~bdIsLoaded(modelName)
                load_system(modelName);
            end
            
            % 2. Kiểm tra trạng thái hiện tại của mô hình
            try
                simStatus = get_param(modelName, 'SimulationStatus');
            catch
                simStatus = 'stopped';
            end
            
            % 3. Nếu mô hình đang chạy hoặc tạm dừng, cần stop trước khi đổi cấu hình FastRestart
            if strcmp(simStatus, 'running') || strcmp(simStatus, 'paused')
                set_param(modelName, 'SimulationCommand', 'stop');
                % Đợi một chút cho mô hình dừng hẳn (nếu cần thiết)
                while ~strcmp(get_param(modelName, 'SimulationStatus'), 'stopped')
                    pause(0.05);
                end
            end
            
            % 4. Bật FastRestart an toàn khi mô hình chắc chắn đã ở trạng thái 'stopped'
            set_param(modelName, 'FastRestart', 'on');
            
            % 5. Bắt đầu chạy mô hình (đã bổ sung dấu phẩy còn thiếu)
            set_param(modelName, 'SimulationCommand', 'start');
            
            % 6. Đẩy giá trị hiện tại của các slider vào ngay khi vừa Start
            app.UpdateRobot();
        
        end

        % Button pushed function: L_IKButton
        function L_IKButtonPushed(app, event)
            % Lấy tọa độ mục tiêu từ Lx, Ly, Lz
            target_pos = [app.EditField_15.Value, app.EditField_16.Value, app.EditField_17.Value];
            
            % Giải nghịch đảo tìm góc khớp tay trái
            q_new = app.inverseKinematicsLeft(target_pos);
            
            % Gán vào các ô góc khớp tay trái (L1 đến L7)
            app.EditField.Value   = q_new(1);
            app.EditField_2.Value = q_new(2);
            app.EditField_3.Value = q_new(3);
            app.EditField_4.Value = q_new(4);
            app.EditField_5.Value = q_new(5);
            app.EditField_6.Value = q_new(6);
            app.EditField_7.Value = q_new(7);
            
            % Đồng bộ thanh trượt tay trái
            app.L1Slider.Value = q_new(1);
            app.L2Slider.Value = q_new(2);
            app.L3Slider.Value = q_new(3);
            app.L4Slider.Value = q_new(4);
            app.L5Slider.Value = q_new(5);
            app.L6Slider.Value = q_new(6);
            app.L7Slider.Value = q_new(7);
            
            % Cập nhật hệ thống
            app.UpdateRobot();
        end

        % Button pushed function: R_IKButton
        function R_IKButtonPushed(app, event)
            % Lấy tọa độ mục tiêu từ Rx, Ry, Rz
            target_pos = [app.EditField_18.Value, app.EditField_19.Value, app.EditField_20.Value];
            
            % Giải nghịch đảo tìm góc khớp tay phải
            q_new = app.inverseKinematicsRight(target_pos);
            
            % Gán vào các ô góc khớp tay phải (R1 đến R7 - EditField_8 đến 14)
            app.EditField_8.Value  = q_new(1);
            app.EditField_9.Value  = q_new(2);
            app.EditField_10.Value = q_new(3);
            app.EditField_11.Value = q_new(4);
            app.EditField_12.Value = q_new(5);
            app.EditField_13.Value = q_new(6);
            app.EditField_14.Value = q_new(7);
            
            % Đồng bộ thanh trượt tay phải
            app.R1Slider.Value = q_new(1);
            app.R2Slider.Value = q_new(2);
            app.R3Slider.Value = q_new(3);
            app.R4Slider.Value = q_new(4);
            app.R5Slider.Value = q_new(5);
            app.R6Slider.Value = q_new(6);
            app.R7Slider.Value = q_new(7);
            
            % Cập nhật hệ thống
            app.UpdateRobot();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @L1SliderValueChanged, true);
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @L2SliderValueChanged, true);
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @L3SliderValueChanged, true);
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @L4SliderValueChanged, true);
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @L5SliderValueChanged, true);
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @L6SliderValueChanged, true);
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_7.ValueChangedFcn = createCallbackFcn(app, @L7SliderValueChanged, true);
            app.EditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_8.ValueChangedFcn = createCallbackFcn(app, @R1SliderValueChanged, true);
            app.EditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_9.ValueChangedFcn = createCallbackFcn(app, @R2SliderValueChanged, true);
            app.EditField_10 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_10.ValueChangedFcn = createCallbackFcn(app, @R3SliderValueChanged, true);
            app.EditField_11 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_11.ValueChangedFcn = createCallbackFcn(app, @EditField_11ValueChanged, true);
            app.EditField_12 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_12.ValueChangedFcn = createCallbackFcn(app, @R5SliderValueChanged, true);
            app.EditField_13 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_13.ValueChangedFcn = createCallbackFcn(app, @R6SliderValueChanged, true);
            app.EditField_14 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_14.ValueChangedFcn = createCallbackFcn(app, @R7SliderValueChanged, true);
            app.EditField_15 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_16 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_17 = uieditfield(app.UIFigure, 'numeric');
            app.LxLabel = uilabel(app.UIFigure);
            app.LxLabel.Text = 'Lx';
            app.LyLabel = uilabel(app.UIFigure);
            app.LyLabel.Text = 'Ly';
            app.LzLabel = uilabel(app.UIFigure);
            app.LzLabel.Text = 'Lz';
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.DEFAULTButton = uibutton(app.UIFigure, 'push');
            app.DEFAULTButton.Text = 'DEFAULT';
            app.CLOSEButton = uibutton(app.UIFigure, 'push');
            app.CLOSEButton.Text = 'CLOSE';
            app.EditField_18 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_19 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_20 = uieditfield(app.UIFigure, 'numeric');
            app.RxLabel = uilabel(app.UIFigure);
            app.RxLabel.Text = 'Rx';
            app.RyLabel = uilabel(app.UIFigure);
            app.RyLabel.Text = 'Ry';
            app.RzLabel = uilabel(app.UIFigure);
            app.RzLabel.Text = 'Rz';
            app.L1SliderLabel = uilabel(app.UIFigure);
            app.L1SliderLabel.Text = 'L1';
            app.L1Slider = uislider(app.UIFigure);
            app.L1Slider.Limits = [-168.5 168.5];
            app.L1Slider.MajorTicks = [];
            app.L1Slider.ValueChangedFcn = createCallbackFcn(app, @L1SliderValueChanged, true);
            app.L2SliderLabel = uilabel(app.UIFigure);
            app.L2SliderLabel.Text = 'L2';
            app.L2Slider = uislider(app.UIFigure);
            app.L2Slider.Limits = [-143.5 43.5];
            app.L2Slider.MajorTicks = [];
            app.L2Slider.ValueChangedFcn = createCallbackFcn(app, @L2SliderValueChanged, true);
            app.L3SliderLabel = uilabel(app.UIFigure);
            app.L3SliderLabel.Text = 'L3';
            app.L3Slider = uislider(app.UIFigure);
            app.L3Slider.Limits = [-168.5 168.5];
            app.L3Slider.MajorTicks = [];
            app.L3Slider.ValueChangedFcn = createCallbackFcn(app, @L3SliderValueChanged, true);
            app.L4SliderLabel = uilabel(app.UIFigure);
            app.L4SliderLabel.Text = 'L4';
            app.L4Slider = uislider(app.UIFigure);
            app.L4Slider.Limits = [-123.5 80];
            app.L4Slider.MajorTicks = [];
            app.L4Slider.ValueChangedFcn = createCallbackFcn(app, @L4SliderValueChanged, true);
            app.L5SliderLabel = uilabel(app.UIFigure);
            app.L5SliderLabel.Text = 'L5';
            app.L5Slider = uislider(app.UIFigure);
            app.L5Slider.Limits = [-290 290];
            app.L5Slider.MajorTicks = [];
            app.L5Slider.ValueChangedFcn = createCallbackFcn(app, @L5SliderValueChanged, true);
            app.L6SliderLabel = uilabel(app.UIFigure);
            app.L6SliderLabel.Text = 'L6';
            app.L6Slider = uislider(app.UIFigure);
            app.L6Slider.Limits = [-88 138];
            app.L6Slider.MajorTicks = [];
            app.L6Slider.ValueChangedFcn = createCallbackFcn(app, @L6SliderValueChanged, true);
            app.L7SliderLabel = uilabel(app.UIFigure);
            app.L7SliderLabel.Text = 'L7';
            app.L7Slider = uislider(app.UIFigure);
            app.L7Slider.Limits = [-229 229];
            app.L7Slider.MajorTicks = [];
            app.L7Slider.ValueChangedFcn = createCallbackFcn(app, @L7SliderValueChanged, true);
            app.R1SliderLabel = uilabel(app.UIFigure);
            app.R1SliderLabel.Text = 'R1';
            app.R1Slider = uislider(app.UIFigure);
            app.R1Slider.Limits = [-168.5 168.5];
            app.R1Slider.MajorTicks = [];
            app.R1Slider.ValueChangedFcn = createCallbackFcn(app, @R1SliderValueChanged, true);
            app.R2SliderLabel = uilabel(app.UIFigure);
            app.R2SliderLabel.Text = 'R2';
            app.R2Slider = uislider(app.UIFigure);
            app.R2Slider.Limits = [-143.5 43.5];
            app.R2Slider.MajorTicks = [];
            app.R2Slider.ValueChangedFcn = createCallbackFcn(app, @R2SliderValueChanged, true);
            app.R3SliderLabel = uilabel(app.UIFigure);
            app.R3SliderLabel.Text = 'R3';
            app.R3Slider = uislider(app.UIFigure);
            app.R3Slider.Limits = [-168.5 168.5];
            app.R3Slider.MajorTicks = [];
            app.R3Slider.ValueChangedFcn = createCallbackFcn(app, @R3SliderValueChanged, true);
            app.R4SliderLabel = uilabel(app.UIFigure);
            app.R4SliderLabel.Text = 'R4';
            app.R4Slider = uislider(app.UIFigure);
            app.R4Slider.Limits = [-123.5 80];
            app.R4Slider.MajorTicks = [];
            app.R4Slider.ValueChangedFcn = createCallbackFcn(app, @EditField_11ValueChanged, true);
            app.R5SliderLabel = uilabel(app.UIFigure);
            app.R5SliderLabel.Text = 'R5';
            app.R5Slider = uislider(app.UIFigure);
            app.R5Slider.Limits = [-290 290];
            app.R5Slider.MajorTicks = [];
            app.R5Slider.ValueChangedFcn = createCallbackFcn(app, @R5SliderValueChanged, true);
            app.R6SliderLabel = uilabel(app.UIFigure);
            app.R6SliderLabel.Text = 'R6';
            app.R6Slider = uislider(app.UIFigure);
            app.R6Slider.Limits = [-88 138];
            app.R6Slider.MajorTicks = [];
            app.R6Slider.ValueChangedFcn = createCallbackFcn(app, @R6SliderValueChanged, true);
            app.R7SliderLabel = uilabel(app.UIFigure);
            app.R7SliderLabel.Text = 'R7';
            app.R7Slider = uislider(app.UIFigure);
            app.R7Slider.Limits = [-229 229];
            app.R7Slider.MajorTicks = [];
            app.R7Slider.ValueChangedFcn = createCallbackFcn(app, @R7SliderValueChanged, true);
            app.L_IKButton = uibutton(app.UIFigure, 'push');
            app.L_IKButton.ButtonPushedFcn = createCallbackFcn(app, @L_IKButtonPushed, true);
            app.L_IKButton.Text = 'L_IK';
            app.R_IKButton = uibutton(app.UIFigure, 'push');
            app.R_IKButton.ButtonPushedFcn = createCallbackFcn(app, @R_IKButtonPushed, true);
            app.R_IKButton.Text = 'R_IK';
            app.TaytriLabel = uilabel(app.UIFigure);
            app.TaytriLabel.Text = 'Tay trái';
            app.TayphiLabel = uilabel(app.UIFigure);
            app.TayphiLabel.Text = 'Tay phải';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = giaodien

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end